classdef capturesample < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        CaptureLampLabel             matlab.ui.control.Label
        CaptureLamp                  matlab.ui.control.Lamp
        StatusLampLabel              matlab.ui.control.Label
        StatusLamp                   matlab.ui.control.Lamp
        PortListBox                  matlab.ui.control.ListBox
        LabeltextEditFieldLabel      matlab.ui.control.Label
        LabeltextEditField           matlab.ui.control.EditField
        CaptureButton                matlab.ui.control.Button
        UpdateListButton             matlab.ui.control.Button
        SampletimesEditFieldLabel    matlab.ui.control.Label
        SettletimesEditFieldLabel    matlab.ui.control.Label
        SettletimesEditField         matlab.ui.control.NumericEditField
        SamplesamountEditFieldLabel  matlab.ui.control.Label
        SamplesamountEditField       matlab.ui.control.NumericEditField
        SwitchplanabLabel            matlab.ui.control.Label
        SwitchplanabEditField        matlab.ui.control.EditField
        SampletimesEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        enose ENoseDAQ % Description
        isCapturing logical % boolean value to determine whether we are currently capturing
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortListBox.Items = cellstr(seriallist);
        end

        % Value changed function: PortListBox
        function PortListBoxValueChanged(app, event)
            % Cannot change COM-port while capturing
            if app.isCapturing == 1
                return
            end
            
            % If there currently is an enose initialized, delete it
            if isvalid(app.enose)
                app.enose.delete()
            end
            
            % Connect ENoseDAQ
            app.StatusLamp.Color = [1 1 0];
            drawnow;
            e = ENoseDAQ(app.PortListBox.Value,0,0);
            e.setSampleRate(300);
            e.setSensitivity(1,128);
            e.setSensitivity(2,128);
            e.setSensitivity(3,128);
            e.setSensitivity(4,128);
            e.setSensitivity(5,128);
            e.setSensitivity(6,128);
            e.setSensitivity(7,128);
            e.setSensitivity(8,128);
            app.StatusLamp.Color = [0 1 0];
            
            app.enose = e;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.enose);
            delete(app);
        end

        % Button pushed function: UpdateListButton
        function UpdateListButtonPushed(app, event)
            app.PortListBox.Items = cellstr(seriallist);
        end

        % Button pushed function: CaptureButton
        function capture(app, event)
            % Constant
            co = [
                    0.0000    0.4470    0.7410;
                    0.8500    0.3250    0.0980;
                    0.9290    0.6940    0.1250;
                    0.4940    0.1840    0.5560;
                    0.4660    0.6740    0.1880;
                    0.3010    0.7450    0.9330;
                    0.6350    0.0780    0.1840
                    ];
            
            % Do not capture if we aren't connected to a session
            if ~IsValid(app.enose)
                errordlg("enose not initialized - select a COM-port first");
                app.CaptureLamp.Color = [1 1 0];
                return
            end
            
            % Get the specified amount of time
            sampleTime = app.SampletimesEditField.Value;
            if sampleTime < 1 || sampleTime > 600
                errordlg("invalid sample time, must be between 1 and 600");
                app.CaptureLamp.Color = [1 1 0];
                return;
            end
            
            % Get the inter-sample period
            settleTime = app.SettletimesEditField.Value;
            if settleTime < 1 || settleTime > 600
                errordlg("invalid settle time, must be between 1 and 600");
                app.CaptureLamp.Color = [1 1 0];
                return;
            end
            
            % Get the amount of samples desired
            sampleAmount = app.SamplesamountEditField.Value;
            if sampleAmount <= 0
                errordlg("invalid sample amount - must be at least 1")
                app.CaptureLamp.Color = [1 1 0];
                return;
            end
            
            % Get the switching markers
            switchPlan = app.SwitchplanabEditField.Value;
            switchPlanSplit = split(switchPlan,",");
            marks = [];
            for i=1:numel(switchPlanSplit)
                str = switchPlanSplit{i}
                mark = str2num(str)
                if isempty(mark)
                    errordlg("invalid mark set: "+str);
                    return;
                end
                if i > 1 && mark < marks(i-1)
                    errordlg("marks must be set in chronological order");
                    return;
                end
                
                marks = [marks mark];
            end
            
            % Get the label
            label = app.LabeltextEditField.Value;
            
            
            % Capture data
            app.isCapturing = true;
            app.CaptureLamp.Color = [0 1 0];
            drawnow;
            
            for i=1:sampleAmount
                % Wait for the system to settle
                pause(settleTime)
                
                % Setup a plan
                data = app.enose.capture(sampleTime,marks);
                app.enose.reset();
                app.CaptureLamp.Color = [1 0 0];
                app.isCapturing = false;
                
                % Save data
                csvwrite(label + "_" + datestr(now,'yyyy-mm-dd_HH-MM-SS') + ".csv",data);
                
                % Plot the data
                t = data(:,1);
                sensors = data(:,2:9);
                
                set(groot,'defaultAxesColorOrder',co)
                plot(app.UIAxes, t,sensors);
                axis(app.UIAxes, [0 sampleTime 0 1024]);
                legend(app.UIAxes, "MQ-2","MQ-3","MQ-4","MQ-5","MQ-6","MQ-7","MQ-8","MQ-138");
                drawnow;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time [s]')
            ylabel(app.UIAxes, 'Sensor reading')
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.5 1];
            app.UIAxes.YTick = [0 0.5 1];
            app.CaptureLampLabel = uilabel(app.UIFigure);
            app.CaptureLampLabel.Text = 'Capture';
            app.CaptureLamp = uilamp(app.UIFigure);
            app.StatusLampLabel = uilabel(app.UIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.UIFigure);
            app.PortListBox = uilistbox(app.UIFigure);
            app.PortListBox.Items = {};
            app.PortListBox.ValueChangedFcn = createCallbackFcn(app, @PortListBoxValueChanged, true);
            app.PortListBox.Value = {};
            app.LabeltextEditFieldLabel = uilabel(app.UIFigure);
            app.LabeltextEditFieldLabel.Text = 'Label [text]';
            app.LabeltextEditField = uieditfield(app.UIFigure, 'text');
            app.CaptureButton = uibutton(app.UIFigure, 'push');
            app.CaptureButton.ButtonPushedFcn = createCallbackFcn(app, @capture, true);
            app.CaptureButton.Text = 'Capture';
            app.UpdateListButton = uibutton(app.UIFigure, 'push');
            app.UpdateListButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateListButtonPushed, true);
            app.UpdateListButton.Text = 'Update List';
            app.SampletimesEditFieldLabel = uilabel(app.UIFigure);
            app.SampletimesEditFieldLabel.Text = 'Sample time [s]';
            app.SampletimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SettletimesEditFieldLabel = uilabel(app.UIFigure);
            app.SettletimesEditFieldLabel.Text = 'Settle time [s]';
            app.SettletimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.SamplesamountEditFieldLabel = uilabel(app.UIFigure);
            app.SamplesamountEditFieldLabel.Text = 'Samples amount';
            app.SamplesamountEditField = uieditfield(app.UIFigure, 'numeric');
            app.SwitchplanabLabel = uilabel(app.UIFigure);
            app.SwitchplanabLabel.Text = 'Switch plan [a,b,..]';
            app.SwitchplanabEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = capturesample

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end